classdef MRI_app < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                  matlab.ui.Figure
        SSIM_3Label               matlab.ui.control.Label
        PSNR_3Label               matlab.ui.control.Label
        SSIM_2Label               matlab.ui.control.Label
        PSNR_2Label               matlab.ui.control.Label
        SSIMLabel                 matlab.ui.control.Label
        PSNRLabel                 matlab.ui.control.Label
        RunButton                 matlab.ui.control.Button
        RunReconstructionLabel    matlab.ui.control.Label
        LoadMRILabel              matlab.ui.control.Label
        LoadButton                matlab.ui.control.Button
        EnableAIEnhancementLabel  matlab.ui.control.Label
        AICheckBox                matlab.ui.control.CheckBox
        SelectMethodLabel         matlab.ui.control.Label
        MethodDropDown            matlab.ui.control.DropDown
        Label                     matlab.ui.control.Label
        UIAxes3                   matlab.ui.control.UIAxes
        UIAxes2                   matlab.ui.control.UIAxes
        UIAxes                    matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        img
        net % Description
    end
    
    methods (Access = private)
        
        
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            load('config.mat','config');

modelPath = fullfile(config.modelsPath,'unet_model.mat');

if exist(modelPath,'file')
    data = load(modelPath);
    app.net = data.net;
    disp('Model loaded');
else
    disp('Model not found');
end
        end

        % Button pushed function: RunButton
        function RunButtonPushed(app, event)
            if isempty(app.img)
    uialert(app.UIFigure, 'Load MRI first!', 'Error');
    return;
end

img = app.img;

% Get selected method
method = app.MethodDropDown.Value;

% Reconstruction
switch method
    case 'CS (FISTA)'
        kspace = fftshift(fft2(img));
        mask = cs.generate_sampling_mask(size(kspace),4);
        kspace_us = kspace .* mask;
        recon = cs.reconstruct_cs_fista(kspace_us, mask);

    case 'SP'
        recon = cs_extra.reconstruct_sp(img);

    case 'IHT'
        recon = cs_extra.reconstruct_iht(img);

    case 'CoSaMP'
        recon = cs_extra.reconstruct_cosamp(img);
end

% Show reconstruction
imshow(recon, [], 'Parent', app.UIAxes2)
title(app.UIAxes2, 'Reconstruction')

% Apply AI if checkbox ON
if app.AICheckBox.Value == 1
    img_ai = utils.apply_ai_gated(recon, app.net);

    imshow(img_ai, [], 'Parent', app.UIAxes3)
    title(app.UIAxes3, 'AI Output')
else
    img_ai = recon;
end

% Metrics
img_ref = imresize(img,[128 128]);
img_eval = imresize(img_ai,[128 128]);

m = utils.compute_metrics(img_eval, img_ref);
disp(m)
app.PSNRLabel.Text = sprintf('PSNR: %.2f', m.PSNR);
app.SSIMLabel.Text = sprintf('SSIM: %.3f', m.SSIM);
        end

        % Button pushed function: LoadButton
        function LoadButtonPushed(app, event)
            folder = uigetdir;

if folder == 0
    return;
end

[img, ~] = utils.load_dicom_series(folder);
img = utils.preprocess_image(img);

app.img = img;

imshow(img, [], 'Parent', app.UIAxes)
title(app.UIAxes, 'Original')
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Original')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.UIAxes2 = uiaxes(app.UIFigure);
            title(app.UIAxes2, 'Reconstruction')
            xlabel(app.UIAxes2, 'X')
            ylabel(app.UIAxes2, 'Y')
            zlabel(app.UIAxes2, 'Z')
            app.UIAxes3 = uiaxes(app.UIFigure);
            title(app.UIAxes3, 'AI Output')
            xlabel(app.UIAxes3, 'X')
            ylabel(app.UIAxes3, 'Y')
            zlabel(app.UIAxes3, 'Z')
            app.Label = uilabel(app.UIFigure);
            app.Label.Text = 'MethodDropDown';
            app.MethodDropDown = uidropdown(app.UIFigure);
            app.MethodDropDown.Items = {'CS (FISTA)', 'SP', 'IHT', 'CoSaMP'};
            app.MethodDropDown.Value = 'CS (FISTA)';
            app.SelectMethodLabel = uilabel(app.UIFigure);
            app.SelectMethodLabel.Text = 'Select Method :';
            app.AICheckBox = uicheckbox(app.UIFigure);
            app.AICheckBox.Text = 'AICheckBox';
            app.EnableAIEnhancementLabel = uilabel(app.UIFigure);
            app.EnableAIEnhancementLabel.Text = 'Enable AI Enhancement :';
            app.LoadButton = uibutton(app.UIFigure, 'push');
            app.LoadButton.ButtonPushedFcn = createCallbackFcn(app, @LoadButtonPushed, true);
            app.LoadButton.Text = 'Load Button';
            app.LoadMRILabel = uilabel(app.UIFigure);
            app.LoadMRILabel.Text = 'Load MRI :';
            app.RunReconstructionLabel = uilabel(app.UIFigure);
            app.RunReconstructionLabel.Text = 'Run Reconstruction :';
            app.RunButton = uibutton(app.UIFigure, 'push');
            app.RunButton.ButtonPushedFcn = createCallbackFcn(app, @RunButtonPushed, true);
            app.RunButton.Text = 'Run Button';
            app.PSNRLabel = uilabel(app.UIFigure);
            app.PSNRLabel.Text = 'PSNR:';
            app.SSIMLabel = uilabel(app.UIFigure);
            app.SSIMLabel.Text = 'SSIM:';
            app.PSNR_2Label = uilabel(app.UIFigure);
            app.PSNR_2Label.Text = 'PSNR_2:';
            app.SSIM_2Label = uilabel(app.UIFigure);
            app.SSIM_2Label.Text = 'SSIM_2:';
            app.PSNR_3Label = uilabel(app.UIFigure);
            app.PSNR_3Label.Text = 'PSNR_3:';
            app.SSIM_3Label = uilabel(app.UIFigure);
            app.SSIM_3Label.Text = 'SSIM_3:';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = MRI_app

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end